classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XSliderLabel              matlab.ui.control.Label
        ApplyregiongrowingButton  matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp;% Description
        app2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, num, image)
            app.callingapp=main;
            global b;
            b=quantize(image,num);
            [x,y]=size(image);
            app.YSlider.Limits=[1 y];
            app.XSlider.Limits=[1 x];
            imshow(b,'parent',app.UIAxes);
        end

        % Button pushed function: ApplyregiongrowingButton
        function ApplyregiongrowingButtonPushed(app, event)
            global b;
            x=app.XSlider.Value;
            x=int16(x);
            y=app.YSlider.Value;
            y=int16(y);
            if(x==0)
                x=1;
            end
            if(y==0)
                y=1;
            end
            app.app2=app2(app,b,x,y);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Quantized Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ApplyregiongrowingButton = uibutton(app.UIFigure, 'push');
            app.ApplyregiongrowingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyregiongrowingButtonPushed, true);
            app.ApplyregiongrowingButton.Text = 'Apply region growing';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 255];
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end