classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        QuantizeButton        matlab.ui.control.Button
        SelectQuantizationlevelDropDown  matlab.ui.control.DropDown
        SelectQuantizationlevelDropDownLabel  matlab.ui.control.Label
        ImageselectionButton  matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        app1 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];

        end

        % Button pushed function: ImageselectionButton
        function ImageselectionButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);
            a=rgb2gray(a);
            imshow(a,'Parent',app.UIAxes2);
        end

        % Button pushed function: QuantizeButton
        function QuantizeButtonPushed(app, event)
           global a;
           value=app.SelectQuantizationlevelDropDown.Value;
           if(value=='4')
               app.app1=app1(app,4,a);
           end
            if(value=='8')
               app.app1=app1(app,8,a);
           end
            
        end

        % Value changed function: SelectQuantizationlevelDropDown
        function SelectQuantizationlevelDropDownValueChanged(app, event)
            value = app.SelectQuantizationlevelDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Query Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grayscale Image')
            zlabel(app.UIAxes2, 'Z')
            app.ImageselectionButton = uibutton(app.UIFigure, 'push');
            app.ImageselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ImageselectionButtonPushed, true);
            app.ImageselectionButton.Text = 'Image selection';
            app.SelectQuantizationlevelDropDownLabel = uilabel(app.UIFigure);
            app.SelectQuantizationlevelDropDownLabel.Text = 'Select  Quantization level';
            app.SelectQuantizationlevelDropDown = uidropdown(app.UIFigure);
            app.SelectQuantizationlevelDropDown.Items = {'4', '8', '16', '32'};
            app.SelectQuantizationlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectQuantizationlevelDropDownValueChanged, true);
            app.SelectQuantizationlevelDropDown.Value = '4';
            app.QuantizeButton = uibutton(app.UIFigure, 'push');
            app.QuantizeButton.ButtonPushedFcn = createCallbackFcn(app, @QuantizeButtonPushed, true);
            app.QuantizeButton.Text = 'Quantize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end